classdef prototypeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        RunButton               matlab.ui.control.Button
        ConfigureLabel          matlab.ui.control.Label
        ModOrderEditField       matlab.ui.control.NumericEditField
        ModOrderEditFieldLabel  matlab.ui.control.Label
        BitsEditField           matlab.ui.control.NumericEditField
        BitsEditFieldLabel      matlab.ui.control.Label
        SNREditField            matlab.ui.control.NumericEditField
        SNREditFieldLabel       matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        Tab1                    matlab.ui.container.Tab
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
        UIAxes4                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        Tab3                    matlab.ui.container.Tab
        UIAxes6                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        noiseTable
        snr = 25
        numBits = 40
        modOrder = 64
    end
    
    methods (Access = private)
        
        function updatePlots(app)
            % plot graphs on all tabs with new data
            app.noiseTable = satTest(app.snr, app.numBits, app.modOrder);

            stem(app.UIAxes, app.noiseTable.txData);
            stem(app.UIAxes2, app.noiseTable.txSig);
            stem(app.UIAxes3, app.noiseTable.awgnSig);
            stem(app.UIAxes4, app.noiseTable.therSig);
            stem(app.UIAxes5, app.noiseTable.phaseSig);
            stem(app.UIAxes6, app.noiseTable.rxData);
        end
    end
    
    methods (Access = public)
        
        function isValid = validateConfigs(app)
            % validate config inputs, for now will use default
            % fallback variables in satTest()
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePlots(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % calculate vectors with new configuration
            % re-draw graphs
            updatePlots(app);
        end

        % Value changed function: SNREditField
        function SNREditFieldValueChanged(app, event)
            value = app.SNREditField.Value;
            app.snr = value;
        end

        % Value changed function: BitsEditField
        function BitsEditFieldValueChanged(app, event)
            value = app.BitsEditField.Value;
            app.numBits = value;
        end

        % Value changed function: ModOrderEditField
        function ModOrderEditFieldValueChanged(app, event)
            value = app.ModOrderEditField.Value;
            app.modOrder = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SNREditFieldLabel = uilabel(app.LeftPanel);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.LeftPanel, 'numeric');
            app.SNREditField.ValueChangedFcn = createCallbackFcn(app, @SNREditFieldValueChanged, true);
            app.SNREditField.Value = 25;
            app.BitsEditFieldLabel = uilabel(app.LeftPanel);
            app.BitsEditFieldLabel.Text = '# Bits';
            app.BitsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BitsEditField.ValueChangedFcn = createCallbackFcn(app, @BitsEditFieldValueChanged, true);
            app.BitsEditField.Value = 40;
            app.ModOrderEditFieldLabel = uilabel(app.LeftPanel);
            app.ModOrderEditFieldLabel.Text = 'Mod Order';
            app.ModOrderEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ModOrderEditField.ValueChangedFcn = createCallbackFcn(app, @ModOrderEditFieldValueChanged, true);
            app.ModOrderEditField.Value = 64;
            app.ConfigureLabel = uilabel(app.LeftPanel);
            app.ConfigureLabel.Text = 'Configure';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab 1';
            app.UIAxes = uiaxes(app.Tab1);
            title(app.UIAxes, 'Input Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Tab1);
            title(app.UIAxes2, 'Modulated Signal')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab 2';
            app.UIAxes3 = uiaxes(app.Tab2);
            title(app.UIAxes3, 'Signal with White Noise')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, 'Signal with Thermal Noise')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab 3';
            app.UIAxes5 = uiaxes(app.Tab3);
            title(app.UIAxes5, 'Signal with Phase Noise')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.Tab3);
            title(app.UIAxes6, 'Output Data')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prototypeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end